classdef Comp_Cinematica < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                   matlab.ui.container.TabGroup
        JOINTTab                   matlab.ui.container.Tab
        CinemticaDiretaLabel       matlab.ui.control.Label
        CDJ6                       matlab.ui.control.Spinner
        Label_3                    matlab.ui.control.Label
        CDJ5                       matlab.ui.control.Spinner
        Label_2                    matlab.ui.control.Label
        CDJ4                       matlab.ui.control.Spinner
        Label                      matlab.ui.control.Label
        CDJ3                       matlab.ui.control.Spinner
        J3Label                    matlab.ui.control.Label
        CDJ2                       matlab.ui.control.Spinner
        J2Label                    matlab.ui.control.Label
        CDJ1                       matlab.ui.control.Spinner
        J1Label                    matlab.ui.control.Label
        CINEMTTab                  matlab.ui.container.Tab
        PCDropDown                 matlab.ui.control.DropDown
        PlanodeCoordDropDownLabel  matlab.ui.control.Label
        InvOriBse                  matlab.ui.container.Panel
        RMinus_3                   matlab.ui.control.Button
        RPlus_3                    matlab.ui.control.Button
        RLabel_3                   matlab.ui.control.Label
        YaLabel_3                  matlab.ui.control.Label
        YaMinus_3                  matlab.ui.control.Button
        YaPlus_3                   matlab.ui.control.Button
        PLabel_3                   matlab.ui.control.Label
        PMinus_3                   matlab.ui.control.Button
        PPlus_3                    matlab.ui.control.Button
        F_PassoOri                 matlab.ui.control.NumericEditField
        Passo1a10EditField_2Label  matlab.ui.control.Label
        InvCoordBse                matlab.ui.container.Panel
        F_PassoPos                 matlab.ui.control.NumericEditField
        Passo1a10mmEditFieldLabel  matlab.ui.control.Label
        ZPlus                      matlab.ui.control.Button
        ZMinus                     matlab.ui.control.Button
        YMinus                     matlab.ui.control.Button
        YLabel                     matlab.ui.control.Label
        YPlus                      matlab.ui.control.Button
        XLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        XMinus                     matlab.ui.control.Button
        XPlus                      matlab.ui.control.Button
        CinemticaInversaLabel      matlab.ui.control.Label
        PRESETCONFIGTab            matlab.ui.container.Tab
        PresetLabel                matlab.ui.control.Label
        PresetJ6                   matlab.ui.control.Spinner
        Label_6                    matlab.ui.control.Label
        PresetJ5                   matlab.ui.control.Spinner
        Label_5                    matlab.ui.control.Label
        PresetJ4                   matlab.ui.control.Spinner
        Label_4                    matlab.ui.control.Label
        PresetJ3                   matlab.ui.control.Spinner
        J3Label_2                  matlab.ui.control.Label
        PresetJ2                   matlab.ui.control.Spinner
        J2Label_2                  matlab.ui.control.Label
        PresetJ1                   matlab.ui.control.Spinner
        J1Label_2                  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ArmMoving
    end

    properties (Access = private, Constant)
        Ang double = 180/pi;
        Metr2Mili double = 1000;
        Rad double = pi/180;
    end
    
    properties (Access = public)
        PresetAng (6,1) double = [0 0 -90 0 -90 0];
        RPY (3,1) double = [0 0 0];
        XYZ (3,1) double = [0 0 0];
        q_Robot (6,1) double = [0 0 0 0 0 0];
    end
    
    
    methods (Access = public)
        
        function results = CinemTrnsl(comp, Mov)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = SE3(Mov);

            if comp.PCDropDown.Value

                TA = TA*T;
            else
                
                TA = T*TA;
            end
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);
        end
        
        function results = CinemOrient(comp, Mov)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            if comp.PCDropDown.Value ~= 1

%                 Mov = TA.R*Mov';
%                 Mov = Mov';
                Mov = Mov*TA.R;
            end
            
            T = rpy2tr(Mov);

            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.PresetJ1.Value = comp.PresetAng(1);
            comp.PresetJ2.Value = comp.PresetAng(2);
            comp.PresetJ3.Value = comp.PresetAng(3);
            comp.PresetJ4.Value = comp.PresetAng(4);
            comp.PresetJ5.Value = comp.PresetAng(5);
            comp.PresetJ6.Value = comp.PresetAng(6);
        end

        % Value changed function: CDJ1
        function CDJ1ValueChanged(comp, event)
            comp.q_Robot(1) = comp.Rad*comp.CDJ1.Value;
            
            notify(comp, 'ArmMoving');
        end

        % Value changed function: CDJ2
        function CDJ2ValueChanged(comp, event)
            comp.q_Robot(2) = comp.Rad*comp.CDJ2.Value;
            
            notify(comp, 'ArmMoving');
        end

        % Value changed function: CDJ3
        function CDJ3ValueChanged(comp, event)
            comp.q_Robot(3) = comp.Rad*comp.CDJ3.Value;

            notify(comp, 'ArmMoving');
        end

        % Value changed function: CDJ4
        function CDJ4ValueChanged(comp, event)
            comp.q_Robot(4) = comp.Rad*comp.CDJ4.Value;
            
            notify(comp, 'ArmMoving');
        end

        % Value changed function: CDJ5
        function CDJ5ValueChanged(comp, event)
            comp.q_Robot(5) = comp.Rad*comp.CDJ5.Value;

            notify(comp, 'ArmMoving');
        end

        % Value changed function: CDJ6
        function CDJ6ValueChanged(comp, event)
            comp.q_Robot(6) = comp.Rad*comp.CDJ6.Value;
            
            notify(comp, 'ArmMoving');
        end

        % Button pushed function: XPlus
        function XPlusButtonPushed(comp, event)
            CinemTrnsl(comp, [(comp.F_PassoPos.Value/comp.Metr2Mili) 0 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: XMinus
        function XMinusButtonPushed(comp, event)
            CinemTrnsl(comp, [(-comp.F_PassoPos.Value/comp.Metr2Mili) 0 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: YPlus
        function YPlusButtonPushed(comp, event)
            CinemTrnsl(comp, [0 (comp.F_PassoPos.Value/comp.Metr2Mili) 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: ZMinus
        function ZMinusButtonPushed(comp, event)
            CinemTrnsl(comp, [0 0 (-comp.F_PassoPos.Value/comp.Metr2Mili)]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: ZPlus
        function ZPlusButtonPushed(comp, event)
            CinemTrnsl(comp, [0 0 (comp.F_PassoPos.Value/comp.Metr2Mili)]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: YMinus
        function YMinusButtonPushed(comp, event)
            CinemTrnsl(comp, [0 (-comp.F_PassoPos.Value/comp.Metr2Mili) 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: RPlus_3
        function RPlus_3ButtonPushed(comp, event)
            CinemOrient(comp, [(comp.F_PassoOri.Value*comp.Rad) 0 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: RMinus_3
        function RMinus_3ButtonPushed(comp, event)
            CinemOrient(comp, [(-comp.F_PassoOri.Value*comp.Rad) 0 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: PPlus_3
        function PPlus_3ButtonPushed(comp, event)
            CinemOrient(comp, [0 (comp.F_PassoOri.Value*comp.Rad) 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: PMinus_3
        function PMinus_3ButtonPushed(comp, event)
            CinemOrient(comp, [0 (-comp.F_PassoOri.Value*comp.Rad) 0]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: YaPlus_3
        function YaPlus_3ButtonPushed(comp, event)
            CinemOrient(comp, [0 0 (comp.F_PassoOri.Value*comp.Rad)]);

            notify(comp, 'ArmMoving');
        end

        % Button pushed function: YaMinus_3
        function YaMinus_3ButtonPushed(comp, event)
            CinemOrient(comp, [0 0 (-comp.F_PassoOri.Value*comp.Rad)]);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function RPlus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([(comp.F_PassoOriTool.Value*comp.Rad) 0 0]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function RMinus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([(-comp.F_PassoOriTool.Value*comp.Rad) 0 0]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function PPlus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([0 (comp.F_PassoOriTool.Value*comp.Rad) 0]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function PMinus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([0 (-comp.F_PassoOriTool.Value*comp.Rad) 0]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function YaPlus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([0 0 (comp.F_PassoOriTool.Value*comp.Rad)]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Callback function
        function YaMinus_2ButtonPushed(comp, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            q = comp.q_Robot;

            TA = robot.fkine(q);
            
            T = rpy2tr([0 0 (-comp.F_PassoOriTool.Value*comp.Rad)]);
            TA = TA*SE3(T);
                
            qRecal = robot.ikine(TA);

            comp.q_Robot(1) = qRecal(1);
            comp.q_Robot(2) = qRecal(2);
            comp.q_Robot(3) = qRecal(3);
            comp.q_Robot(4) = qRecal(4);
            comp.q_Robot(5) = qRecal(5);
            comp.q_Robot(6) = qRecal(6);

            notify(comp, 'ArmMoving');
        end

        % Value changed function: PresetJ1
        function PresetJ1ValueChanged(comp, event)
            comp.PresetAng(1) = comp.PresetJ1.Value;
            
        end

        % Value changed function: PresetJ2
        function PresetJ2ValueChanged(comp, event)
            comp.PresetAng(2) = comp.PresetJ2.Value;
            
        end

        % Value changed function: PresetJ3
        function PresetJ3ValueChanged(comp, event)
            comp.PresetAng(3) = comp.PresetJ3.Value;
            
        end

        % Value changed function: PresetJ4
        function PresetJ4ValueChanged(comp, event)
            comp.PresetAng(4) = comp.PresetJ4.Value;
            
        end

        % Value changed function: PresetJ5
        function PresetJ5ValueChanged(comp, event)
            comp.PresetAng(5) = comp.PresetJ5.Value;
            
        end

        % Value changed function: PresetJ6
        function PresetJ6ValueChanged(comp, event)
            comp.PresetAng(6) = comp.PresetJ6.Value;
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.CDJ1.Value = round(comp.Ang*comp.q_Robot(1),2);
            comp.CDJ2.Value = round(comp.Ang*comp.q_Robot(2),2);
            comp.CDJ3.Value = round(comp.Ang*comp.q_Robot(3),2);
            comp.CDJ4.Value = round(comp.Ang*comp.q_Robot(4),2);
            comp.CDJ5.Value = round(comp.Ang*comp.q_Robot(5),2);
            comp.CDJ6.Value = round(comp.Ang*comp.q_Robot(6),2);

            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 348 276];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.JOINTTab = uitab(comp.TabGroup);
            comp.JOINTTab.Title = 'JOINT';
            comp.J1Label = uilabel(comp.JOINTTab);
            comp.J1Label.Text = 'θ1';
            comp.CDJ1 = uispinner(comp.JOINTTab);
            comp.CDJ1.Limits = [-360 360];
            comp.CDJ1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ1ValueChanged, true);
            comp.J2Label = uilabel(comp.JOINTTab);
            comp.J2Label.Text = 'θ2';
            comp.CDJ2 = uispinner(comp.JOINTTab);
            comp.CDJ2.Limits = [-360 360];
            comp.CDJ2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ2ValueChanged, true);
            comp.J3Label = uilabel(comp.JOINTTab);
            comp.J3Label.Text = 'θ3';
            comp.CDJ3 = uispinner(comp.JOINTTab);
            comp.CDJ3.Limits = [-360 360];
            comp.CDJ3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ3ValueChanged, true);
            comp.Label = uilabel(comp.JOINTTab);
            comp.Label.Text = 'θ4';
            comp.CDJ4 = uispinner(comp.JOINTTab);
            comp.CDJ4.Limits = [-360 360];
            comp.CDJ4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ4ValueChanged, true);
            comp.Label_2 = uilabel(comp.JOINTTab);
            comp.Label_2.Text = 'θ5';
            comp.CDJ5 = uispinner(comp.JOINTTab);
            comp.CDJ5.Limits = [-360 360];
            comp.CDJ5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ5ValueChanged, true);
            comp.Label_3 = uilabel(comp.JOINTTab);
            comp.Label_3.Text = 'θ6';
            comp.CDJ6 = uispinner(comp.JOINTTab);
            comp.CDJ6.Limits = [-360 360];
            comp.CDJ6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDJ6ValueChanged, true);
            comp.CinemticaDiretaLabel = uilabel(comp.JOINTTab);
            comp.CinemticaDiretaLabel.Text = 'Cinemática Direta:';
            comp.CINEMTTab = uitab(comp.TabGroup);
            comp.CINEMTTab.Title = 'CINEMÁT.';
            comp.CinemticaInversaLabel = uilabel(comp.CINEMTTab);
            comp.CinemticaInversaLabel.Text = 'Cinemática Inversa:';
            comp.InvCoordBse = uipanel(comp.CINEMTTab);
            comp.XPlus = uibutton(comp.InvCoordBse, 'push');
            comp.XPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @XPlusButtonPushed, true);
            comp.XPlus.Text = '+';
            comp.XMinus = uibutton(comp.InvCoordBse, 'push');
            comp.XMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @XMinusButtonPushed, true);
            comp.XMinus.Text = '-';
            comp.ZLabel = uilabel(comp.InvCoordBse);
            comp.ZLabel.Text = 'Z';
            comp.XLabel = uilabel(comp.InvCoordBse);
            comp.XLabel.Text = 'X';
            comp.YPlus = uibutton(comp.InvCoordBse, 'push');
            comp.YPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YPlusButtonPushed, true);
            comp.YPlus.Text = '+';
            comp.YLabel = uilabel(comp.InvCoordBse);
            comp.YLabel.Text = 'Y';
            comp.YMinus = uibutton(comp.InvCoordBse, 'push');
            comp.YMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YMinusButtonPushed, true);
            comp.YMinus.Text = '-';
            comp.ZMinus = uibutton(comp.InvCoordBse, 'push');
            comp.ZMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZMinusButtonPushed, true);
            comp.ZMinus.Text = '-';
            comp.ZPlus = uibutton(comp.InvCoordBse, 'push');
            comp.ZPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZPlusButtonPushed, true);
            comp.ZPlus.Text = '+';
            comp.Passo1a10mmEditFieldLabel = uilabel(comp.InvCoordBse);
            comp.Passo1a10mmEditFieldLabel.Text = 'Passo [1 a 10 mm]';
            comp.F_PassoPos = uieditfield(comp.InvCoordBse, 'numeric');
            comp.F_PassoPos.Limits = [1 10];
            comp.F_PassoPos.RoundFractionalValues = 'on';
            comp.F_PassoPos.Value = 1;
            comp.InvOriBse = uipanel(comp.CINEMTTab);
            comp.Passo1a10EditField_2Label = uilabel(comp.InvOriBse);
            comp.Passo1a10EditField_2Label.Text = 'Passo [1 a 10 °]';
            comp.F_PassoOri = uieditfield(comp.InvOriBse, 'numeric');
            comp.F_PassoOri.Limits = [1 10];
            comp.F_PassoOri.RoundFractionalValues = 'on';
            comp.F_PassoOri.Value = 1;
            comp.PPlus_3 = uibutton(comp.InvOriBse, 'push');
            comp.PPlus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PPlus_3ButtonPushed, true);
            comp.PPlus_3.Text = '+';
            comp.PMinus_3 = uibutton(comp.InvOriBse, 'push');
            comp.PMinus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PMinus_3ButtonPushed, true);
            comp.PMinus_3.Text = '-';
            comp.PLabel_3 = uilabel(comp.InvOriBse);
            comp.PLabel_3.Text = 'P';
            comp.YaPlus_3 = uibutton(comp.InvOriBse, 'push');
            comp.YaPlus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YaPlus_3ButtonPushed, true);
            comp.YaPlus_3.Text = '+';
            comp.YaMinus_3 = uibutton(comp.InvOriBse, 'push');
            comp.YaMinus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @YaMinus_3ButtonPushed, true);
            comp.YaMinus_3.Text = '-';
            comp.YaLabel_3 = uilabel(comp.InvOriBse);
            comp.YaLabel_3.Text = 'Y';
            comp.RLabel_3 = uilabel(comp.InvOriBse);
            comp.RLabel_3.Text = 'R';
            comp.RPlus_3 = uibutton(comp.InvOriBse, 'push');
            comp.RPlus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RPlus_3ButtonPushed, true);
            comp.RPlus_3.Text = '+';
            comp.RMinus_3 = uibutton(comp.InvOriBse, 'push');
            comp.RMinus_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RMinus_3ButtonPushed, true);
            comp.RMinus_3.Text = '-';
            comp.PlanodeCoordDropDownLabel = uilabel(comp.CINEMTTab);
            comp.PlanodeCoordDropDownLabel.Text = 'Plano de Coord.:';
            comp.PCDropDown = uidropdown(comp.CINEMTTab);
            comp.PCDropDown.Items = {'BASE', 'TOOL'};
            comp.PCDropDown.ItemsData = [0 1];
            comp.PCDropDown.Value = 0;
            comp.PRESETCONFIGTab = uitab(comp.TabGroup);
            comp.PRESETCONFIGTab.Title = 'PRESET CONFIG.';
            comp.J1Label_2 = uilabel(comp.PRESETCONFIGTab);
            comp.J1Label_2.Text = 'θ1';
            comp.PresetJ1 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ1.Limits = [-360 360];
            comp.PresetJ1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ1ValueChanged, true);
            comp.J2Label_2 = uilabel(comp.PRESETCONFIGTab);
            comp.J2Label_2.Text = 'θ2';
            comp.PresetJ2 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ2.Limits = [-360 360];
            comp.PresetJ2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ2ValueChanged, true);
            comp.J3Label_2 = uilabel(comp.PRESETCONFIGTab);
            comp.J3Label_2.Text = 'θ3';
            comp.PresetJ3 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ3.Limits = [-360 360];
            comp.PresetJ3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ3ValueChanged, true);
            comp.PresetJ3.Value = -90;
            comp.Label_4 = uilabel(comp.PRESETCONFIGTab);
            comp.Label_4.Text = 'θ4';
            comp.PresetJ4 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ4.Limits = [-360 360];
            comp.PresetJ4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ4ValueChanged, true);
            comp.Label_5 = uilabel(comp.PRESETCONFIGTab);
            comp.Label_5.Text = 'θ5';
            comp.PresetJ5 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ5.Limits = [-360 360];
            comp.PresetJ5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ5ValueChanged, true);
            comp.PresetJ5.Value = -90;
            comp.Label_6 = uilabel(comp.PRESETCONFIGTab);
            comp.Label_6.Text = 'θ6';
            comp.PresetJ6 = uispinner(comp.PRESETCONFIGTab);
            comp.PresetJ6.Limits = [-360 360];
            comp.PresetJ6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PresetJ6ValueChanged, true);
            comp.PresetLabel = uilabel(comp.PRESETCONFIGTab);
            comp.PresetLabel.Text = 'Configuração Preset:';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end