classdef Projeto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        EmergnciaButton                matlab.ui.control.Button
        ObterContiPosCheckBox          matlab.ui.control.CheckBox
        ObterPosComauButton            matlab.ui.control.Button
        RetornarPosAtualButton         matlab.ui.control.Button
        DefinirPosAtualButton          matlab.ui.control.Button
        ProcessoGraphLabel             matlab.ui.control.Label
        ComauLamp                      matlab.ui.control.Lamp
        ComauLampLabel                 matlab.ui.control.Label
        CoppeliaLamp                   matlab.ui.control.Lamp
        CoppeliaLampLabel              matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        CinemticaTab                   matlab.ui.container.Tab
        Comp_Cinematica                Comp_Cinematica
        ControleLinearTab              matlab.ui.container.Tab
        Comp_Controle                  Comp_Controle
        ControleManualTab              matlab.ui.container.Tab
        Comp_ContrBttn                 Comp_ContrBttn
        ConfiguracoesdeGraficoPanel_2  matlab.ui.container.Panel
        IncGarraCBox                   matlab.ui.control.CheckBox
        ExportPosCheckBox              matlab.ui.control.CheckBox
        ExportarDadosButton            matlab.ui.control.Button
        TabGroup4                      matlab.ui.container.TabGroup
        CoppeliaTab                    matlab.ui.container.Tab
        DesconectarCoppeliaButton      matlab.ui.control.Button
        CarregarCoppeliaButton         matlab.ui.control.Button
        ConectarCoppeliaButton         matlab.ui.control.Button
        ComauTab                       matlab.ui.container.Tab
        RetornarPosEsperaButton        matlab.ui.control.Button
        DesconectarComauButton         matlab.ui.control.Button
        CarregarComauButton            matlab.ui.control.Button
        ConectarComauButton            matlab.ui.control.Button
        ConfiguracoesdeGraficoPanel    matlab.ui.container.Panel
        RobotTwinStatusLabel           matlab.ui.control.Label
        CamCima                        matlab.ui.control.Button
        CamBaixo                       matlab.ui.control.Button
        CamReset                       matlab.ui.control.Button
        CamDir                         matlab.ui.control.Button
        CamEsq                         matlab.ui.control.Button
        TwinLamp                       matlab.ui.control.Lamp
        RepetirSeqButton               matlab.ui.control.Button
        DeletarSeqButton               matlab.ui.control.Button
        ControleSeqTamanhoLabel        matlab.ui.control.Label
        AtualizarButton                matlab.ui.control.Button
        PosPresetButton                matlab.ui.control.Button
        PosEsperaButton                matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        JuntasTab                      matlab.ui.container.Tab
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Joint6Field                    matlab.ui.control.NumericEditField
        Junta6Label                    matlab.ui.control.Label
        Joint5Field                    matlab.ui.control.NumericEditField
        Junta5Label                    matlab.ui.control.Label
        Joint4Field                    matlab.ui.control.NumericEditField
        Junta4Label                    matlab.ui.control.Label
        Joint3Field                    matlab.ui.control.NumericEditField
        Junta3Label                    matlab.ui.control.Label
        Joint2Field                    matlab.ui.control.NumericEditField
        Join1Label                     matlab.ui.control.Label
        Joint1Field                    matlab.ui.control.NumericEditField
        Join1Label_2                   matlab.ui.control.Label
        PosTab                         matlab.ui.container.Tab
        mmLabel_3                      matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        YawField                       matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        PitchField                     matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RowField                       matlab.ui.control.NumericEditField
        RowEditFieldLabel              matlab.ui.control.Label
        ZField                         matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YField                         matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XField                         matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)


        AngleRadJ = [0 0 -pi/2 0 pi/2 0];
        
        PosiX
        PosiY
        PosiZ
        Row
        Pitch
        Yaw

        RowPitYaw;
        Translacao;

        Metr2Mili = 100;
        TamGarra = 0.15; % Description
    end
    
    properties (Access = public)
        CoppComau % Description
        CommComau % Description
        SmartSixModel % Description
        SmartSixConfig
        ExprtTargPos
        ExprtPos % Description
        ExprtCount = 1;
        ExprtTemp datetime % Description
    end
    
    methods (Access = private)
        
        function results = OffEdit(app)
            app.RowField.Editable = 'off';
            app.PitchField.Editable = 'off';
            app.YawField.Editable = 'off';
            app.XField.Editable = 'off';
            app.YField.Editable = 'off';
            app.ZField.Editable = 'off';
            app.Joint6Field.Editable = 'off';
            app.Joint5Field.Editable = 'off';
            app.Joint4Field.Editable = 'off';
            app.Joint3Field.Editable = 'off';
            app.Joint2Field.Editable = 'off';
            app.Joint1Field.Editable = 'off';
        end
        
        function results = ArmCalculation(app, AngleJoint)
            Ang = 180/pi;
            
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            if app.IncGarraCBox.Value
                Ttool = SE3([1 0 0 0; 0 1 0 0; 0 0 1 app.TamGarra; 0 0 0 1]);
                robot.tool = Ttool;
            end

            % Ponto inicial
            q0 = AngleJoint;
            app.Comp_Cinematica.q_Robot = q0;

            app.RowPitYaw = tr2rpy(robot.fkine(q0), 'deg');
            app.Translacao = transl(robot.fkine(q0));

%             R = SO3(robot.fkine(q0));
%             app.RowPitYaw = app.RowPitYaw*round(R.R);

            app.Comp_Cinematica.RPY = app.RowPitYaw;
            app.Comp_Cinematica.XYZ = app.Translacao;

%             app.RowPitYaw = robot.fkine(q0).R*app.RowPitYaw';
%             R = tr2rpy(SO3(robot.fkine(q0)),'deg')


            app.Row = app.RowPitYaw(1);
            app.RowField.Value = round(app.Row,2);
            app.Pitch = app.RowPitYaw(2);
            app.PitchField.Value = round(app.Pitch,2);
            app.Yaw = app.RowPitYaw(3);
            app.YawField.Value = round(app.Yaw,2);
            app.PosiX = app.Translacao(1);
            app.XField.Value = round(app.PosiX*1000,2);
            app.PosiY = app.Translacao(2);
            app.YField.Value = round(app.PosiY*1000,2);
            app.PosiZ = app.Translacao(3);
            app.ZField.Value = round(app.PosiZ*1000,2);


            app.Joint1Field.Value = round(Ang*AngleJoint(1),2);
            app.Joint2Field.Value = round(Ang*AngleJoint(2),2);
            app.Joint3Field.Value = round(Ang*AngleJoint(3),2);
            app.Joint4Field.Value = round(Ang*AngleJoint(4),2);
            app.Joint5Field.Value = round(Ang*AngleJoint(5),2);
            app.Joint6Field.Value = round(Ang*AngleJoint(6),2);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            OffEdit(app);
            ArmCalculation(app, app.AngleRadJ);
            app.Comp_Controle.q_Start = app.AngleRadJ;

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:\n %d/%d',size(app.Comp_Controle.q_SeqIndOp,2), size(app.Comp_Controle.q_SeqIndOp,2));
            app.ProcessoGraphLabel.Text = sprintf('%s','Simulação: MATLAB');

            app.SmartSixModel = importrobot('smart5six_description-master\robot\comau.urdf');
            
            app.SmartSixConfig = homeConfiguration(app.SmartSixModel);
                     
            app.SmartSixConfig(1).JointPosition = app.AngleRadJ(1);
            app.SmartSixConfig(2).JointPosition = app.AngleRadJ(2);
            app.SmartSixConfig(3).JointPosition = app.AngleRadJ(3);
            app.SmartSixConfig(4).JointPosition = app.AngleRadJ(4);
            app.SmartSixConfig(5).JointPosition = app.AngleRadJ(5);
            app.SmartSixConfig(6).JointPosition = app.AngleRadJ(6);
            
            ax = show(app.SmartSixModel,app.SmartSixConfig);

            xlim(ax, [-1.5 1.5]);  % Limite do eixo X
            ylim(ax, [-1.5 1.5]);  % Limite do eixo Y
            zlim(ax, [-0.3 2.7]);  % Limite do eixo Z

            parentfig = get(ax, 'Parent');
            parentfig.Visible = 'off';

            copyobj(ax,app.Panel);

        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            app.SmartSixConfig(1).JointPosition = app.AngleRadJ(1);
            app.SmartSixConfig(2).JointPosition = app.AngleRadJ(2);
            app.SmartSixConfig(3).JointPosition = app.AngleRadJ(3);
            app.SmartSixConfig(4).JointPosition = app.AngleRadJ(4);
            app.SmartSixConfig(5).JointPosition = app.AngleRadJ(5);
            app.SmartSixConfig(6).JointPosition = app.AngleRadJ(6);

            cla(app.Panel.Children);

            ax = show(app.SmartSixModel,app.SmartSixConfig);

            children = get(ax, 'Children');
            copyobj(children,app.Panel.Children);
           
        end

        % Button pushed function: PosEsperaButton
        function PosEsperaButtonPushed(app, event)
            app.AngleRadJ(1) = 0;
            app.AngleRadJ(2) = 0;
            app.AngleRadJ(3) = -pi/2;
            app.AngleRadJ(4) = 0;
            app.AngleRadJ(5) = pi/2;
            app.AngleRadJ(6) = 0;

            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;
        end

        % Button pushed function: PosPresetButton
        function PosPresetButtonPushed(app, event)
            Rad = pi/180;

            app.AngleRadJ(1) = app.Comp_Cinematica.PresetAng(1)*Rad;
            app.AngleRadJ(2) = app.Comp_Cinematica.PresetAng(2)*Rad;
            app.AngleRadJ(3) = app.Comp_Cinematica.PresetAng(3)*Rad;
            app.AngleRadJ(4) = app.Comp_Cinematica.PresetAng(4)*Rad;
            app.AngleRadJ(5) = app.Comp_Cinematica.PresetAng(5)*Rad;
            app.AngleRadJ(6) = app.Comp_Cinematica.PresetAng(6)*Rad;

            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
        end

        % Callback function: Comp_Controle
        function Seq_Movimento(app, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'd', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            if app.IncGarraCBox.Value
                Ttool = SE3([1 0 0 0; 0 1 0 0; 0 0 1 app.TamGarra; 0 0 0 1]);
                robot.tool = Ttool;
            end

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:\n %d/%d',size(app.Comp_Controle.q_SeqIndOp,2), size(app.Comp_Controle.q_SeqIndOp,2));
            app.ProcessoGraphLabel.Text = sprintf('%s','Simulação: MATLAB');               

            q0 =  app.Comp_Controle.q_SeqIndOp(:,end)';

            app.SmartSixConfig(1).JointPosition = q0(1);
            app.SmartSixConfig(2).JointPosition = q0(2);
            app.SmartSixConfig(3).JointPosition = q0(3);
            app.SmartSixConfig(4).JointPosition = q0(4);
            app.SmartSixConfig(5).JointPosition = q0(5);
            app.SmartSixConfig(6).JointPosition = q0(6);

            cla(app.Panel.Children);

            ax = show(app.SmartSixModel,app.SmartSixConfig);

            children = get(ax, 'Children');
            copyobj(children,app.Panel.Children);

            ArmCalculation(app, app.Comp_Controle.q_SeqIndOp(:,end)');
            app.AngleRadJ = app.Comp_Controle.q_SeqIndOp(:,end)';

        end

        % Button pushed function: ConectarCoppeliaButton
        function ConectarCoppeliaButtonPushed(app, event)
            addpath('./lib/libCoppelia/');           
            
            %% Configuracoes iniciais
            
            % cria o objeto de comando do comau no coppeliasim
            app.CoppComau = ComauSim();

            app.Comp_ContrBttn.CoppComau = app.CoppComau;
            
            % inicia a simulacao
            app.CoppComau.startSimulation();
            app.CoppeliaLamp.Color = 'g';
        end

        % Button pushed function: CarregarCoppeliaButton
        function CarregarCoppeliaButtonPushed(app, event)
            % delta t do controlador
            deltaT = 0.01;
            
            % Define a posicao atual do sm6 como o vetor inicial
            %q = deg2rad(q_i);
            if app.Comp_Controle.Seq_Size == 0
                msgbox("Faltando Sequência");
                return
            end

            q = app.Comp_Controle.q_SeqIndOp(:,1)';
            % envia o robo para a posicao inicial
            app.CoppComau.setJointTargetPosition(q);
            pause(2);

            if app.ExportPosCheckBox.Value == 1

                prevCount = app.ExprtCount;
            end

            total_points = size(app.Comp_Controle.q_SeqIndOp,2);
            for i=1:total_points
            
                fprintf('Enviando ponto: %d/%d \n', i, total_points);
            
                % envia target position para as juntas do sm6
                app.CoppComau.setJointTargetPosition(app.Comp_Controle.q_SeqIndOp(:,i)');

                q_lei = app.CoppComau.getJointPosition();
                app.ExprtTemp(:,app.ExprtCount) = datetime("now","Format","mm:ss.SSS");

                ArmCalculation(app, q_lei);

                if app.ExportPosCheckBox.Value == 1
                    app.ExprtPos(:,app.ExprtCount) = q_lei;
                    app.ExprtCount = i + prevCount;
                end
            
                % pequeno pause de acordo com o deltaT estipulado
                pause(deltaT);
            end

            if app.ExportPosCheckBox.Value == 1
                app.ExprtTargPos = cat(2,app.ExprtTargPos,app.Comp_Controle.q_SeqIndOp);
            end

            pause(5);
        end

        % Button pushed function: DesconectarCoppeliaButton
        function DesconectarCoppeliaButtonPushed(app, event)
            % finaliza a simulacao
            app.CoppComau.stopSimulation();

            app.CoppeliaLamp.Color = 'r';
        end

        % Button pushed function: ConectarComauButton
        function ConectarComauButtonPushed(app, event)
            app.CommComau = ComauComm('tcpip', '172.22.121.2', 4121);
            pause(3);

            app.Comp_ContrBttn.CommComau = app.CommComau;
            
            app.ComauLamp.Color = 'g';
            
            % Limita a velocidade do robo
            %app.CommComau.setArmOverride(min(60,max(fix(app.Comp_Controle.Vel/10),10)));
            
        end

        % Button pushed function: CarregarComauButton
        function CarregarComauButtonPushed(app, event)
            app.ProcessoGraphLabel.Text = sprintf('%s','Simulação: COMAU Smart Six');
            
            if app.Comp_Controle.Seq_Size == 0
                msgbox("Faltando Sequência");
                return
            end
            
            q_lei = deg2rad(app.CommComau.getJointPositions());

            app.ExprtTemp(:,app.ExprtCount) = datetime("now","Format","mm:ss.SSS");       
            
            app.SmartSixConfig(1).JointPosition = q_lei(1);
            app.SmartSixConfig(2).JointPosition = q_lei(2);
            app.SmartSixConfig(3).JointPosition = q_lei(3);
            app.SmartSixConfig(4).JointPosition = q_lei(4);
            app.SmartSixConfig(5).JointPosition = q_lei(5);
            app.SmartSixConfig(6).JointPosition = q_lei(6);

            cla(app.Panel.Children);

            ax = show(app.SmartSixModel,app.SmartSixConfig);

            children = get(ax, 'Children');
            copyobj(children,app.Panel.Children);

            ArmCalculation(app, q_lei');

            if app.ExportPosCheckBox.Value == 1
                app.ExprtPos(:,app.ExprtCount) = q_lei';
                prevCount = app.ExprtCount;
                app.ExprtCount = prevCount + 1;
            end

            pause(0.1);

            app.CommComau.setJointPositions(rad2deg(app.Comp_Controle.q_SeqIndOp(:,1)));

            %% Loop de envio das posicoes de juntas ao robo
            total_points = size(app.Comp_Controle.q_SeqIndOp,2);
            for i=2:total_points
            
               %fprintf('Enviando ponto: %d/%d \n', i, total_points);
            
               % Envia um conjunto de posicao para as juntas
                app.CommComau.setJointPositionsFly(rad2deg(app.Comp_Controle.q_SeqIndOp(:,i)), true);
                
                q_lei = deg2rad(app.CommComau.getJointPositions());

                app.ExprtTemp(:,app.ExprtCount) = datetime("now","Format","mm:ss.SSS");
                
                app.SmartSixConfig(1).JointPosition = q_lei(1);
                app.SmartSixConfig(2).JointPosition = q_lei(2);
                app.SmartSixConfig(3).JointPosition = q_lei(3);
                app.SmartSixConfig(4).JointPosition = q_lei(4);
                app.SmartSixConfig(5).JointPosition = q_lei(5);
                app.SmartSixConfig(6).JointPosition = q_lei(6);
                
                cla(app.Panel.Children);
                
                show(app.SmartSixModel,app.SmartSixConfig);
                
                children = get(ax, 'Children');
                copyobj(children,app.Panel.Children);
                
                ArmCalculation(app, q_lei');
                
                if app.ExportPosCheckBox.Value == 1
                    app.ExprtPos(:,app.ExprtCount) = q_lei';
                    app.ExprtCount = i + prevCount;
                end

            end

            if app.ExportPosCheckBox.Value == 1
                app.ExprtTargPos = cat(2,app.ExprtTargPos,app.Comp_Controle.q_SeqIndOp);
            end

            if app.ObterContiPosCheckBox.Value

                app.AngleRadJ = q_lei';

                ArmCalculation(app, app.AngleRadJ);
                app.AtualizarButtonPushed();
                app.Comp_Controle.q_Start = app.AngleRadJ;
            end
        end

        % Button pushed function: DesconectarComauButton
        function DesconectarComauButtonPushed(app, event)
            app.CommComau.close

            app.ComauLamp.Color = 'r';          
        end

        % Button pushed function: DeletarSeqButton
        function DeletarSeqButtonPushed(app, event)
            app.Comp_Controle.Seq_Size = 0;
            app.Comp_Controle.Seq_LastOp = 0;
            app.Comp_Controle.q_Start = app.Comp_Controle.q_PrevPos;
            app.AngleRadJ = app.Comp_Controle.q_PrevPos;
            app.Comp_Controle.q_SeqIndOp = [];

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:\n %d/%d',size(app.Comp_Controle.q_SeqIndOp,2), size(app.Comp_Controle.q_SeqIndOp,2));
        end

        % Button pushed function: RepetirSeqButton
        function RepetirSeqButtonPushed(app, event)
            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:\n 1/%d',size(app.Comp_Controle.q_SeqIndOp,2));
            app.ProcessoGraphLabel.Text = sprintf('%s','Simulação: MATLAB');

            q0 = app.Comp_Controle.q_SeqIndOp(:,1)';

            app.SmartSixConfig(1).JointPosition = q0(1);
            app.SmartSixConfig(2).JointPosition = q0(2);
            app.SmartSixConfig(3).JointPosition = q0(3);
            app.SmartSixConfig(4).JointPosition = q0(4);
            app.SmartSixConfig(5).JointPosition = q0(5);
            app.SmartSixConfig(6).JointPosition = q0(6);

            cla(app.Panel.Children);

            ax = show(app.SmartSixModel,app.SmartSixConfig);
            
            for i= 2:size(app.Comp_Controle.q_SeqIndOp,2)

                app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:\n %d/%d',i,size(app.Comp_Controle.q_SeqIndOp,2));

                q0 = app.Comp_Controle.q_SeqIndOp(:,i)';
                
                app.SmartSixConfig(1).JointPosition = q0(1);
                app.SmartSixConfig(2).JointPosition = q0(2);
                app.SmartSixConfig(3).JointPosition = q0(3);
                app.SmartSixConfig(4).JointPosition = q0(4);
                app.SmartSixConfig(5).JointPosition = q0(5);
                app.SmartSixConfig(6).JointPosition = q0(6);
    
                cla(app.Panel.Children);
    
                show(app.SmartSixModel,app.SmartSixConfig);

                children = get(ax, 'Children');
                copyobj(children,app.Panel.Children);
                
                ArmCalculation(app, app.Comp_Controle.q_SeqIndOp(:,i)');

                pause(0.01);
                
            end
        end

        % Button pushed function: DefinirPosAtualButton
        function DefinirPosAtualButtonPushed(app, event)
            app.Comp_Controle.q_Start = app.AngleRadJ;

        end

        % Button pushed function: RetornarPosEsperaButton
        function RetornarPosEsperaButtonPushed(app, event)
            app.CommComau.setJointPositions([0 0 -90 0 90 0]');
        end

        % Button pushed function: ObterPosComauButton
        function ObterPosComauButtonPushed(app, event)
            app.CommComau = ComauComm('tcpip', '172.22.121.2', 4121);
            pause(3);
            
            app.ComauLamp.Color = 'g';
            
            app.AngleRadJ = deg2rad(app.CommComau.getJointPositions()');

            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;

            app.CommComau.close

            app.ComauLamp.Color = 'r';
        end

        % Button pushed function: EmergnciaButton
        function EmergnciaButtonPushed(app, event)
            %app.CommComau = ComauComm('tcpip', '172.22.121.2', 4121);
            %pause(3);

            app.CommComau.pauseProgram
%             app.CommComau.pauseAllPrograms

            app.EmergnciaButton.Enable = 'off';
            
            app.ComauLamp.Color = 'y';

            app.CommComau.close

            warndlg(sprintf(['Aviso: Sistema pausado, para reiniciar o funcionamento do manipulador execute novamente CommauComm no TouchPendant:' ...
                '\n -Disabilitar o Drive ( R5)' ...
                '\n -Aperte Execução (F2)' ...
                '\n -Seleciona a primera opção Reinicializar(Reset) e aperte Enter ' ...
                '\n -Em seguida abilite o Drive (R5) \n -Clique em Start ']), ...
                'Parada de Emergência')
        end

        % Callback function: Comp_Cinematica
        function Comp_CinematicaArmMoving(app, event)
            app.AngleRadJ(1) = app.Comp_Cinematica.q_Robot(1);
            app.AngleRadJ(2) = app.Comp_Cinematica.q_Robot(2);
            app.AngleRadJ(3) = app.Comp_Cinematica.q_Robot(3);
            app.AngleRadJ(4) = app.Comp_Cinematica.q_Robot(4);
            app.AngleRadJ(5) = app.Comp_Cinematica.q_Robot(5);
            app.AngleRadJ(6) = app.Comp_Cinematica.q_Robot(6);
            
            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;
        end

        % Callback function
        function Comp_ContrBttnRobotTwinEv(app, event)
            app.TwinLamp.Color = 'y';

            ArmCalculation(app, app.Comp_ContrBttn.q_RobotTwin);

            app.SmartSixConfig(1).JointPosition = app.Comp_ContrBttn.q_RobotTwin(1);
            app.SmartSixConfig(2).JointPosition = app.Comp_ContrBttn.q_RobotTwin(2);
            app.SmartSixConfig(3).JointPosition = app.Comp_ContrBttn.q_RobotTwin(3);
            app.SmartSixConfig(4).JointPosition = app.Comp_ContrBttn.q_RobotTwin(4);
            app.SmartSixConfig(5).JointPosition = app.Comp_ContrBttn.q_RobotTwin(5);
            app.SmartSixConfig(6).JointPosition = app.Comp_ContrBttn.q_RobotTwin(6);

            cla(app.Panel.Children);

            show(app.SmartSixModel,app.SmartSixConfig);
            ax = gca;
            children = get(ax, 'Children');
            copyobj(children,app.Panel.Children);
            
            app.TwinLamp.Color = 'r';
            app.RobotTwinButton.Value = 0;
        end

        % Button pushed function: CamCima
        function CamCimaButtonPushed(app, event)
            [rot,el] = view(app.Panel.Children);

            NewRot = floor(rot / 45)*45;
            NewEl = (floor(el / 45)*45) + 45;

            view(app.Panel.Children,NewRot,NewEl);
        end

        % Button pushed function: CamBaixo
        function CamBaixoButtonPushed(app, event)
            [rot,el] = view(app.Panel.Children);

            NewRot = floor(rot / 45)*45;
            NewEl = (floor(el / 45)*45) - 45;

            view(app.Panel.Children,NewRot,NewEl);
        end

        % Button pushed function: CamEsq
        function CamEsqButtonPushed(app, event)
            [rot,el] = view(app.Panel.Children);

            NewRot = (floor(rot / 45)*45) - 45;
            NewEl = floor(el / 45)*45;

            view(app.Panel.Children,NewRot,NewEl);
        end

        % Button pushed function: CamDir
        function CamDirButtonPushed(app, event)
            [rot,el] = view(app.Panel.Children);

            NewRot = (floor(rot / 45)*45) + 45;
            NewEl = floor(el / 45)*45;

            view(app.Panel.Children,NewRot,NewEl);
        end

        % Button pushed function: CamReset
        function CamResetButtonPushed(app, event)
            view(app.Panel.Children,135,8);
        end

        % Button pushed function: ExportarDadosButton
        function ExportarDadosButtonPushed(app, event)
            if app.ExportPosCheckBox.Value == 1   
                if app.ExprtCount > 1
                    assignin('base','ManimpTargtPos',app.ExprtTargPos);
                    assignin('base','ManimpPos',app.ExprtPos);
                    assignin('base','ManimpTemp',app.ExprtTemp);
                    assignin('base','Contag',app.ExprtCount);

                    app.ExprtCount = 1;

                elseif app.Comp_ContrBttn.ExprtCount > 1
                    assignin('base','ManimpTargtPos',app.Comp_ContrBttn.ExprtTargPos);
                    assignin('base','ManimpPos',app.Comp_ContrBttn.ExprtPos);
                    assignin('base','ManimpTemp',app.Comp_ContrBttn.ExprtTemp);
                    assignin('base','Contag',app.Comp_ContrBttn.ExprtCount);

                    app.Comp_ContrBttn.ExprtCount = 1;

                end
            end
        end

        % Value changed function: ExportPosCheckBox
        function ExportPosCheckBoxValueChanged(app, event)
            app.Comp_ContrBttn.ExportarDados = app.ExportPosCheckBox.Value;
            
        end

        % Button pushed function: RetornarPosAtualButton
        function RetornarPosAtualButtonPushed(app, event)
            app.AngleRadJ = app.Comp_Controle.q_Start;
            
            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;
        end

        % Value changed function: IncGarraCBox
        function IncGarraCBoxValueChanged(app, event)
            app.Comp_ContrBttn.GarraCheck = app.IncGarraCBox.Value;
            app.Comp_ContrBttn.GarraTam = app.TamGarra;

            app.Comp_Controle.GarraCheck = app.IncGarraCBox.Value;
            app.Comp_Controle.GarraTam = app.TamGarra;

            ArmCalculation(app, app.AngleRadJ);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.JuntasTab = uitab(app.TabGroup2);
            app.JuntasTab.Title = 'Juntas';
            app.Join1Label_2 = uilabel(app.JuntasTab);
            app.Join1Label_2.Text = 'Junta 1';
            app.Joint1Field = uieditfield(app.JuntasTab, 'numeric');
            app.Join1Label = uilabel(app.JuntasTab);
            app.Join1Label.Text = 'Junta 2';
            app.Joint2Field = uieditfield(app.JuntasTab, 'numeric');
            app.Junta3Label = uilabel(app.JuntasTab);
            app.Junta3Label.Text = 'Junta 3';
            app.Joint3Field = uieditfield(app.JuntasTab, 'numeric');
            app.Junta4Label = uilabel(app.JuntasTab);
            app.Junta4Label.Text = 'Junta 4';
            app.Joint4Field = uieditfield(app.JuntasTab, 'numeric');
            app.Junta5Label = uilabel(app.JuntasTab);
            app.Junta5Label.Text = 'Junta 5';
            app.Joint5Field = uieditfield(app.JuntasTab, 'numeric');
            app.Junta6Label = uilabel(app.JuntasTab);
            app.Junta6Label.Text = 'Junta 6';
            app.Joint6Field = uieditfield(app.JuntasTab, 'numeric');
            app.Label = uilabel(app.JuntasTab);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.JuntasTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.JuntasTab);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.JuntasTab);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.JuntasTab);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.JuntasTab);
            app.Label_6.Text = '°';
            app.PosTab = uitab(app.TabGroup2);
            app.PosTab.Title = 'Pos.';
            app.XEditFieldLabel = uilabel(app.PosTab);
            app.XEditFieldLabel.Text = 'X';
            app.XField = uieditfield(app.PosTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.PosTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YField = uieditfield(app.PosTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.PosTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZField = uieditfield(app.PosTab, 'numeric');
            app.RowEditFieldLabel = uilabel(app.PosTab);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowField = uieditfield(app.PosTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.PosTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchField = uieditfield(app.PosTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.PosTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawField = uieditfield(app.PosTab, 'numeric');
            app.Label_7 = uilabel(app.PosTab);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.PosTab);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.PosTab);
            app.Label_9.Text = '°';
            app.mmLabel = uilabel(app.PosTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.PosTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.PosTab);
            app.mmLabel_3.Text = 'mm';
            app.Panel = uipanel(app.UIFigure);
            app.PosEsperaButton = uibutton(app.UIFigure, 'push');
            app.PosEsperaButton.ButtonPushedFcn = createCallbackFcn(app, @PosEsperaButtonPushed, true);
            app.PosEsperaButton.Text = 'Posição Espera';
            app.PosPresetButton = uibutton(app.UIFigure, 'push');
            app.PosPresetButton.ButtonPushedFcn = createCallbackFcn(app, @PosPresetButtonPushed, true);
            app.PosPresetButton.Text = 'Posição Preset';
            app.ConfiguracoesdeGraficoPanel = uipanel(app.UIFigure);
            app.ConfiguracoesdeGraficoPanel.Title = 'Gráfico';
            app.AtualizarButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.ControleSeqTamanhoLabel = uilabel(app.ConfiguracoesdeGraficoPanel);
            app.ControleSeqTamanhoLabel.Text = 'Controle Seq Tamanho:';
            app.DeletarSeqButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.DeletarSeqButton.ButtonPushedFcn = createCallbackFcn(app, @DeletarSeqButtonPushed, true);
            app.DeletarSeqButton.Text = 'Deletar Seq';
            app.RepetirSeqButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.RepetirSeqButton.ButtonPushedFcn = createCallbackFcn(app, @RepetirSeqButtonPushed, true);
            app.RepetirSeqButton.Text = 'Repetir Seq';
            app.TwinLamp = uilamp(app.ConfiguracoesdeGraficoPanel);
            app.CamEsq = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.CamEsq.ButtonPushedFcn = createCallbackFcn(app, @CamEsqButtonPushed, true);
            app.CamEsq.Text = '🠔';
            app.CamDir = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.CamDir.ButtonPushedFcn = createCallbackFcn(app, @CamDirButtonPushed, true);
            app.CamDir.Text = '🠖';
            app.CamReset = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.CamReset.ButtonPushedFcn = createCallbackFcn(app, @CamResetButtonPushed, true);
            app.CamReset.Text = '⭯';
            app.CamBaixo = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.CamBaixo.ButtonPushedFcn = createCallbackFcn(app, @CamBaixoButtonPushed, true);
            app.CamBaixo.Text = '🠗';
            app.CamCima = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.CamCima.ButtonPushedFcn = createCallbackFcn(app, @CamCimaButtonPushed, true);
            app.CamCima.Text = '🠕';
            app.RobotTwinStatusLabel = uilabel(app.ConfiguracoesdeGraficoPanel);
            app.RobotTwinStatusLabel.Text = 'Estado Robot Twin:';
            app.ConfiguracoesdeGraficoPanel_2 = uipanel(app.UIFigure);
            app.ConfiguracoesdeGraficoPanel_2.Title = 'Braço';
            app.TabGroup4 = uitabgroup(app.ConfiguracoesdeGraficoPanel_2);
            app.CoppeliaTab = uitab(app.TabGroup4);
            app.CoppeliaTab.Title = 'Coppelia';
            app.ConectarCoppeliaButton = uibutton(app.CoppeliaTab, 'push');
            app.ConectarCoppeliaButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarCoppeliaButtonPushed, true);
            app.ConectarCoppeliaButton.Text = 'Conectar Coppelia';
            app.CarregarCoppeliaButton = uibutton(app.CoppeliaTab, 'push');
            app.CarregarCoppeliaButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarCoppeliaButtonPushed, true);
            app.CarregarCoppeliaButton.Text = 'Carregar Coppelia';
            app.DesconectarCoppeliaButton = uibutton(app.CoppeliaTab, 'push');
            app.DesconectarCoppeliaButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarCoppeliaButtonPushed, true);
            app.DesconectarCoppeliaButton.Text = 'Desconectar Coppelia';
            app.ComauTab = uitab(app.TabGroup4);
            app.ComauTab.Title = 'Comau';
            app.ConectarComauButton = uibutton(app.ComauTab, 'push');
            app.ConectarComauButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarComauButtonPushed, true);
            app.ConectarComauButton.Text = 'Conectar Comau';
            app.CarregarComauButton = uibutton(app.ComauTab, 'push');
            app.CarregarComauButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarComauButtonPushed, true);
            app.CarregarComauButton.Text = 'Carregar Comau';
            app.DesconectarComauButton = uibutton(app.ComauTab, 'push');
            app.DesconectarComauButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarComauButtonPushed, true);
            app.DesconectarComauButton.Text = 'Desconectar Comau';
            app.RetornarPosEsperaButton = uibutton(app.ComauTab, 'push');
            app.RetornarPosEsperaButton.ButtonPushedFcn = createCallbackFcn(app, @RetornarPosEsperaButtonPushed, true);
            app.RetornarPosEsperaButton.Text = 'Retornar Pos. Espera';
            app.ExportarDadosButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.ExportarDadosButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarDadosButtonPushed, true);
            app.ExportarDadosButton.Text = 'Exportar Dados';
            app.ExportPosCheckBox = uicheckbox(app.ConfiguracoesdeGraficoPanel_2);
            app.ExportPosCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportPosCheckBoxValueChanged, true);
            app.ExportPosCheckBox.Text = 'Salvar Posições';
            app.IncGarraCBox = uicheckbox(app.ConfiguracoesdeGraficoPanel_2);
            app.IncGarraCBox.ValueChangedFcn = createCallbackFcn(app, @IncGarraCBoxValueChanged, true);
            app.IncGarraCBox.Text = 'Incluir Garra';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.CinemticaTab = uitab(app.TabGroup3);
            app.CinemticaTab.Title = 'Cinemática';
            app.Comp_Cinematica = Comp_Cinematica(app.CinemticaTab);
            app.Comp_Cinematica.ArmMovingFcn = createCallbackFcn(app, @Comp_CinematicaArmMoving, true);
            app.ControleLinearTab = uitab(app.TabGroup3);
            app.ControleLinearTab.Title = 'Controle Linear';
            app.Comp_Controle = Comp_Controle(app.ControleLinearTab);
            app.Comp_Controle.Vel = [];
            app.Comp_Controle.Seq_MovimentoFcn = createCallbackFcn(app, @Seq_Movimento, true);
            app.ControleManualTab = uitab(app.TabGroup3);
            app.ControleManualTab.Title = 'Controle Manual';
            app.Comp_ContrBttn = Comp_ContrBttn(app.ControleManualTab);
            app.CoppeliaLampLabel = uilabel(app.UIFigure);
            app.CoppeliaLampLabel.Text = 'Coppelia';
            app.CoppeliaLamp = uilamp(app.UIFigure);
            app.ComauLampLabel = uilabel(app.UIFigure);
            app.ComauLampLabel.Text = 'Comau';
            app.ComauLamp = uilamp(app.UIFigure);
            app.ProcessoGraphLabel = uilabel(app.UIFigure);
            app.ProcessoGraphLabel.Text = 'Processo: -Graph';
            app.DefinirPosAtualButton = uibutton(app.UIFigure, 'push');
            app.DefinirPosAtualButton.ButtonPushedFcn = createCallbackFcn(app, @DefinirPosAtualButtonPushed, true);
            app.DefinirPosAtualButton.Text = 'Definir Posição Inicial';
            app.RetornarPosAtualButton = uibutton(app.UIFigure, 'push');
            app.RetornarPosAtualButton.ButtonPushedFcn = createCallbackFcn(app, @RetornarPosAtualButtonPushed, true);
            app.RetornarPosAtualButton.Text = 'Retornar Posição Inicial';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ObterPosComauButton = uibutton(app.Panel_2, 'push');
            app.ObterPosComauButton.ButtonPushedFcn = createCallbackFcn(app, @ObterPosComauButtonPushed, true);
            app.ObterPosComauButton.Text = 'Obter Posição Comau';
            app.ObterContiPosCheckBox = uicheckbox(app.Panel_2);
            app.ObterContiPosCheckBox.Text = 'Obter Continuamente Posição ';
            app.EmergnciaButton = uibutton(app.Panel_2, 'push');
            app.EmergnciaButton.ButtonPushedFcn = createCallbackFcn(app, @EmergnciaButtonPushed, true);
            app.EmergnciaButton.Text = 'Emergência';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projeto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end